classdef LynxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Axes                       matlab.ui.control.UIAxes
        LynxRobotJointAnglesPanel  matlab.ui.container.Panel
        theta1_deg                 matlab.ui.control.NumericEditField
        theta2_deg                 matlab.ui.control.NumericEditField
        theta3_deg                 matlab.ui.control.NumericEditField
        theta4_deg                 matlab.ui.control.NumericEditField
        theta5_deg                 matlab.ui.control.NumericEditField
        RadiansLabel               matlab.ui.control.Label
        DegreesLabel               matlab.ui.control.Label
        EditFieldLabel             matlab.ui.control.Label
        theta1_rad                 matlab.ui.control.NumericEditField
        theta2_rad                 matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        theta3_rad                 matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        theta4_rad                 matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        theta5_rad                 matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        LynxRobotEndEffectorPositionOrientationPanel  matlab.ui.container.Panel
        psi_deg                    matlab.ui.control.NumericEditField
        phi_deg                    matlab.ui.control.NumericEditField
        RadiansLabel_2             matlab.ui.control.Label
        DegreesLabel_2             matlab.ui.control.Label
        x                          matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        y                          matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        z                          matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        psi_rad                    matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        phi_rad                    matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
    end

    
    properties(Access = private)
        stlFiles = ["Base.STL" "Frame1.STL" "Frame2.STL" "Frame3.STL" "Frame4.STL" "Frame5.STL"];
        frames = 6;
        
        thetas = []
        triMap = {}
        
        baseNormTransformations = {
            Transformation.t().g().x(-193.89).y(-166.838).z(-170).m()
            Transformation.t().g().x(-55.29).y(-29.33).z(-48.33).m()
            Transformation.t().g().x(-132.95).y(-25.48).z(-52.7550).m()
            Transformation.t().g().x(-11.58).y(-23.47).z(-45.03).r().yd(-90).xd(90).m()
            Transformation.t().g().x(-29.68).y(-62.13).z(-167.8).m()
            Transformation.t().g().x(-16.903).y(-45.39).z(-51.88).m()
        }
    
        normTransformations = {};
    
        triColors = [
            0.8980 0.4510 0.4510
            0.7294 0.4078 0.7843
            0.3922 0.7098 0.9647
            0.3020 0.7137 0.6745
            0.6824 0.8353 0.5059
            1.0000 0.8353 0.3098
        ]
    end
    
    methods (Access = private)
        function recalcTransformations(app)
            dh = dh_table(app.thetas);
            struct2table(dh)
            trans = Transformation.b();
            
            app.normTransformations{1} = app.baseNormTransformations{1};
            
            for i = 1:app.frames - 1
                trans = trans.dh(dh(i).theta, dh(i).d * 10, dh(i).a * 10, dh(i).alpha);
                app.normTransformations{i + 1} = trans.m() * app.baseNormTransformations{i + 1};
            end
        end
        
        function recalcTriMap(app)
            for i = 1:app.frames
                stl = stlread(app.stlFiles(i));
                
                P = stl.Points;
                T = stl.ConnectivityList;
                P = (app.normTransformations{i} * [P'; ones(1, length(P))])';
%                 P = (app.baseNormTransformations{i} * [P'; ones(1, length(P))])';
                P = P(:, 1:3);
                
                app.triMap{i} = triangulation(T, P);
            end
        end
        
        function plot(app)
            app.recalcTransformations();
            app.recalcTriMap();
            
            cla(app.Axes);
            hold(app.Axes, 'on');
            
            for i = 1:app.frames
                app.plotTri(app.triMap{i}, app.triColors(i, :));
            end
            
            light(app.Axes, 'Position', app.Axes.CameraPosition, 'Style', 'infinite');
            axis(app.Axes, 'equal');
        end
        
        function plotTri(app, tri, color)
            p = trisurf(tri, 'FaceColor', color, ...
                         'EdgeColor', 'none', ...
                         'FaceLighting', 'gouraud', ...
                         'AmbientStrength', 0.15, ...
                         'Parent', app.Axes);
            material(p, 'dull');
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, thetas)
            app.thetas = thetas + [0 pi/2 0 pi/2 0];
            app.plot();
        end

        % Value changed function: theta1_rad
        function theta1_radValueChanged(app, event)
            app.thetas(1) = app.theta1_rad.Value;
            app.theta1_deg.Value = app.thetas(1) * 180 / pi;
            app.plot();
        end

        % Value changed function: theta2_rad
        function theta2_radValueChanged(app, event)
            app.thetas(2) = app.theta2_rad.Value;
            app.theta2_deg.Value = app.thetas(2) * 180 / pi + pi / 2;
            app.plot();
        end

        % Value changed function: theta3_rad
        function theta3_radValueChanged(app, event)
            app.thetas(3) = app.theta3_rad.Value;
            app.theta3_deg.Value = app.thetas(3) * 180 / pi;
            app.plot();
        end

        % Value changed function: theta4_rad
        function theta4_radValueChanged(app, event)
            app.thetas(4) = app.theta4_rad.Value;
            app.theta4_deg.Value = app.thetas(4) * 180 / pi + pi / 2;
            app.plot();
        end

        % Value changed function: theta5_rad
        function theta5_radValueChanged(app, event)
            app.thetas(5) = app.theta5_rad.Value;
            app.theta5_deg.Value = app.thetas(5) * 180 / pi;
            app.plot();
        end

        % Value changed function: theta1_deg
        function theta1_degValueChanged(app, event)
            app.thetas(1) = app.theta1_deg.Value * pi / 180;
            app.theta1_rad.Value = app.thetas(1);
            app.plot();
        end

        % Value changed function: theta2_deg
        function theta2_degValueChanged(app, event)
            app.thetas(2) = app.theta2_deg.Value * pi / 180 + pi / 2;
            app.theta2_rad.Value = app.thetas(2);
            app.plot();
        end

        % Value changed function: theta3_deg
        function theta3_degValueChanged(app, event)
            app.thetas(3) = app.theta3_deg.Value * pi / 180;
            app.theta3_rad.Value = app.thetas(3);
            app.plot();
        end

        % Value changed function: theta4_deg
        function theta4_degValueChanged(app, event)
            app.thetas(4) = app.theta4_deg.Value * pi / 180 + pi / 2;
            app.theta4_rad.Value = app.thetas(4);
            app.plot();
        end

        % Value changed function: theta5_deg
        function theta5_degValueChanged(app, event)
            app.thetas(5) = app.theta5_deg.Value * pi / 180;
            app.theta5_rad.Value = app.thetas(5);
            app.plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, '')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.LynxRobotJointAnglesPanel = uipanel(app.UIFigure);
            app.LynxRobotJointAnglesPanel.Title = 'Lynx Robot Joint Angles';
            app.theta1_deg = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta1_deg.ValueDisplayFormat = '%11.3g';
            app.theta1_deg.ValueChangedFcn = createCallbackFcn(app, @theta1_degValueChanged, true);
            app.theta2_deg = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta2_deg.ValueDisplayFormat = '%11.3g';
            app.theta2_deg.ValueChangedFcn = createCallbackFcn(app, @theta2_degValueChanged, true);
            app.theta3_deg = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta3_deg.ValueDisplayFormat = '%11.3g';
            app.theta3_deg.ValueChangedFcn = createCallbackFcn(app, @theta3_degValueChanged, true);
            app.theta4_deg = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta4_deg.ValueDisplayFormat = '%11.3g';
            app.theta4_deg.ValueChangedFcn = createCallbackFcn(app, @theta4_degValueChanged, true);
            app.theta5_deg = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta5_deg.ValueDisplayFormat = '%11.3g';
            app.theta5_deg.ValueChangedFcn = createCallbackFcn(app, @theta5_degValueChanged, true);
            app.RadiansLabel = uilabel(app.LynxRobotJointAnglesPanel);
            app.RadiansLabel.Text = 'Radians';
            app.DegreesLabel = uilabel(app.LynxRobotJointAnglesPanel);
            app.DegreesLabel.Text = 'Degrees';
            app.EditFieldLabel = uilabel(app.LynxRobotJointAnglesPanel);
            app.EditFieldLabel.Text = 'θ1';
            app.theta1_rad = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta1_rad.ValueDisplayFormat = '%11.3g';
            app.theta1_rad.ValueChangedFcn = createCallbackFcn(app, @theta1_radValueChanged, true);
            app.theta2_rad = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta2_rad.ValueDisplayFormat = '%11.3g';
            app.theta2_rad.ValueChangedFcn = createCallbackFcn(app, @theta2_radValueChanged, true);
            app.Label = uilabel(app.LynxRobotJointAnglesPanel);
            app.Label.Text = 'θ2';
            app.theta3_rad = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta3_rad.ValueDisplayFormat = '%11.3g';
            app.theta3_rad.ValueChangedFcn = createCallbackFcn(app, @theta3_radValueChanged, true);
            app.Label_2 = uilabel(app.LynxRobotJointAnglesPanel);
            app.Label_2.Text = 'θ3';
            app.theta4_rad = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta4_rad.ValueDisplayFormat = '%11.3g';
            app.theta4_rad.ValueChangedFcn = createCallbackFcn(app, @theta4_radValueChanged, true);
            app.Label_3 = uilabel(app.LynxRobotJointAnglesPanel);
            app.Label_3.Text = 'θ4';
            app.theta5_rad = uieditfield(app.LynxRobotJointAnglesPanel, 'numeric');
            app.theta5_rad.ValueDisplayFormat = '%11.3g';
            app.theta5_rad.ValueChangedFcn = createCallbackFcn(app, @theta5_radValueChanged, true);
            app.Label_4 = uilabel(app.LynxRobotJointAnglesPanel);
            app.Label_4.Text = 'θ5';
            app.LynxRobotEndEffectorPositionOrientationPanel = uipanel(app.UIFigure);
            app.LynxRobotEndEffectorPositionOrientationPanel.Title = 'Lynx Robot End Effector Position & Orientation';
            app.psi_deg = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.phi_deg = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.RadiansLabel_2 = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.RadiansLabel_2.Text = 'Radians';
            app.DegreesLabel_2 = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.DegreesLabel_2.Text = 'Degrees';
            app.x = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.xEditFieldLabel = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.xEditFieldLabel.Text = 'x';
            app.y = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.yEditFieldLabel.Text = 'y';
            app.z = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.zEditFieldLabel.Text = 'z';
            app.psi_rad = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.Label_6 = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.Label_6.Text = 'ψ';
            app.phi_rad = uieditfield(app.LynxRobotEndEffectorPositionOrientationPanel, 'numeric');
            app.Label_5 = uilabel(app.LynxRobotEndEffectorPositionOrientationPanel);
            app.Label_5.Text = 'φ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LynxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end